classdef portada_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        uipanel1     matlab.ui.container.Panel
        pushbutton5  matlab.ui.control.Button
        pushbutton4  matlab.ui.control.Button
        pushbutton3  matlab.ui.control.Button
        axes1        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function portada_OpeningFcn(app, varargin)
            % --- Executes just before portada is made visible.
            
            % asegura que la aplicación aparezca en pantalla
            movegui(app.figure1, 'onscreen');
            
            % Configuración de argumentos de compatibilidad para la migración
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % Carga y despliega el archivo que es la imagen de portada 
            a=imread('porta.PNG');
            image(a)
            axis off

            % Inicializa la estructura de datos del entorno
            handles.output = hObject;
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton3
        function pushbutton3_Callback(app, event)
            % --- Executes on button press in pushbutton3.
            
            % Conversión de argumentos para el entorno de compatibilidad
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Limpia la consola de MATLAB
            clc; 
            % Lanza la aplicación secundaria correspondiente al pozo Tipo J
            run('tipo__j_App.mlapp');
            % Cierra la ventana actual de la portada para liberar memoria
            delete(handles.figure1); 

        end

        % Button pushed function: pushbutton4
        function pushbutton4_Callback(app, event)
            % --- Executes on button press in pushbutton4.
            
            % Conversión de argumentos para el entorno de compatibilidad
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Limpia la consola de MATLAB
            clc; 
            % Lanza la aplicación secundaria correspondiente al pozo Tipo S
            run('tipo_s_App.mlapp');
            % Cierra la ventana actual de la portada para liberar memoria
            delete(handles.figure1); 

        end

        % Button pushed function: pushbutton5
        function pushbutton5_Callback(app, event)
            % --- Executes on button press in pushbutton5.
            
            % Conversión de argumentos para el entorno de compatibilidad
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % % Limpia la consola de MATLAB
            clc; 
            % Lanza la aplicación secundaria correspondiente al pozo Tipo S
            % Modificado
            run('tipo_smoddd_App.mlapp');
            % Cierra la ventana actual de la portada para liberar memoria
            delete(handles.figure1); 

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'portada';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Tag = 'uipanel1';
            app.axes1 = uiaxes(app.uipanel1);
            app.axes1.Tag = 'axes1';
            app.pushbutton3 = uibutton(app.uipanel1, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3_Callback, true);
            app.pushbutton3.Tag = 'pushbutton3';
            app.pushbutton3.Text = 'Tipo J';
            app.pushbutton4 = uibutton(app.uipanel1, 'push');
            app.pushbutton4.ButtonPushedFcn = createCallbackFcn(app, @pushbutton4_Callback, true);
            app.pushbutton4.Tag = 'pushbutton4';
            app.pushbutton4.Text = 'Tipo S';
            app.pushbutton5 = uibutton(app.uipanel1, 'push');
            app.pushbutton5.ButtonPushedFcn = createCallbackFcn(app, @pushbutton5_Callback, true);
            app.pushbutton5.Tag = 'pushbutton5';
            app.pushbutton5.Text = 'Tipo S Modificado';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = portada_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)portada_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end