classdef tipo__j_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        uipanel1     matlab.ui.container.Panel
        pushbutton2  matlab.ui.control.Button
        edit20       matlab.ui.control.EditField
        edit19       matlab.ui.control.EditField
        text21       matlab.ui.control.Label
        text20       matlab.ui.control.Label
        edit18       matlab.ui.control.EditField
        text19       matlab.ui.control.Label
        edit17       matlab.ui.control.EditField
        text18       matlab.ui.control.Label
        edit16       matlab.ui.control.EditField
        text17       matlab.ui.control.Label
        edit15       matlab.ui.control.EditField
        text16       matlab.ui.control.Label
        edit14       matlab.ui.control.EditField
        text15       matlab.ui.control.Label
        text14       matlab.ui.control.Label
        edit13       matlab.ui.control.EditField
        edit12       matlab.ui.control.EditField
        text13       matlab.ui.control.Label
        edit11       matlab.ui.control.EditField
        edit10       matlab.ui.control.EditField
        edit9        matlab.ui.control.EditField
        edit8        matlab.ui.control.EditField
        edit7        matlab.ui.control.EditField
        edit6        matlab.ui.control.EditField
        edit5        matlab.ui.control.EditField
        edit4        matlab.ui.control.EditField
        edit3        matlab.ui.control.EditField
        edit2        matlab.ui.control.EditField
        edit1        matlab.ui.control.EditField
        pushbutton1  matlab.ui.control.Button
        text12       matlab.ui.control.Label
        text11       matlab.ui.control.Label
        text10       matlab.ui.control.Label
        text9        matlab.ui.control.Label
        text8        matlab.ui.control.Label
        text7        matlab.ui.control.Label
        text6        matlab.ui.control.Label
        text5        matlab.ui.control.Label
        text4        matlab.ui.control.Label
        text3        matlab.ui.control.Label
        text2        matlab.ui.control.Label
        axes1        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function tipo__j_OpeningFcn(app, varargin)
            % --- Executes just before tipo__j is made visible.
            
            % Asegura que la aplicación aparezca visible en la pantalla
            movegui(app.figure1, 'onscreen');
            
            % Inicializa argumentos de compatibilidad para el entorno GUIDE portado
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            handles.output = hObject;

            % Formatea el color de todas las etiquetas de texto, para que
            % resalte el texto de las casillas
            etiquetas = findobj(hObject, 'Type', 'uilabel');
            set(etiquetas, 'FontColor', [0 0 0]);

            % Formatea
            % dinámicamente el color del texto en las cajas de entrada
            cajas_entrada = findobj(hObject, 'Type', 'uieditfield');
            set(cajas_entrada, 'FontColor', [0 0 0]);
            
            % Actualiza los punteros de la estructura de control de la app
            guidata(hObject, handles);

        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % --- Executes on button press in pushbutton1.
            
            % Inicialización de variables de interfaz (boton de calcular)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Limpia el plano cartesiano de gráficos anteriores
            cla reset

            % Llama los datos de entrada proporcionados por el usuario

            % Desplazamiento Horizontal del objetivo
            DH=str2double(get(handles.edit1,'String'))
            % Profundidad Vertical Verdadera del objetivo
            Pvv=str2double(get(handles.edit2,'String'))
            % Tasa de incremento angular (Build-up rate) en Angulo [°]
            a=str2double(get(handles.edit3,'String'))
            % Tasa de incremento angular (Build-up rate) cada que tantos
            % [m]
            met=str2double(get(handles.edit13,'String'))
            % Profundidad de construccion del angulo
            KOP=str2double(get(handles.edit4,'String'))

            % ----- Estos datos no son necesarios para el programa, solo es para
            % ver profundidades de interes
            % Si quieres saber datos de interes conociendo la profundidad Vertical de interés
            Pvvi=str2double(get(handles.edit5,'String'))
            % Si quieres saber datos de interes conociendo la profundidad
            % desarrollada de interes
            PDdi=str2double(get(handles.edit18,'String'))

            % ---- Inicia el procedimiento matematico
            % Tasa de incremento angular (Build-up rate)
            RIA=a/met
            % Radio de Curvatura calculado de la sección de construcción
            Rc=(180/(pi*RIA))

            % Condicional geométrica para determinar el ángulo máximo de inclinación (Theta)
            if Rc>DH
                ang=asind(Rc/(((Rc-DH)^2+(Pvv-KOP)^2)^(1/2)))-atand((Rc-DH)/(Pvv-KOP))
            
            else
                ang=180-atand((Pvv-KOP)/(DH-Rc))-acosd((Rc/(Pvv-KOP))*sind(atand((Pvv-KOP)/(DH-Rc))))
            end
            % Parámetros críticos del final de la sección curva (FC) y zona tangente
            % Longitud de la curva
            Lc=ang/RIA
            % Profundidad Desarrollada al Final de la Curva
            PDFC=KOP+Lc
            omega=asind(Rc/(((Rc-DH)^2+(Pvv-KOP)^2)^(1/2)))
            % Profundidad Vertical al Final de la Curva
            Pvvfc=KOP+sind(ang)*Rc
            % Longitud de la sección rectilínea o tangente
            ztan=(Pvv-Pvvfc)/cosd(ang)
            % Desplazamiento Horizontal al Final de la Curva
            DHFC=Rc*(1-cosd(ang))
            % Profundidad Desarrollada Total del Pozo
            PDTP=PDFC+ztan

            % --- Para graficar el diseño del pozo J
            % Invierte el eje Y para simular profundidad de superficie
            % hasta el objetivo a perforar
            set(gca,'yDir','reverse')
            axis([-20,(DH+50),0,Pvv])
            title('Tipo J')
            xlabel('Desplazamiento Horizontal [m]')
            ylabel('Profundidad [m]')
            hold on
            grid

            % Para graficar la trayectoria, se dividio en 3 segmentos, para
            % identificar la parte vertical, la de construccion del angulo
            % y la tangente, con su respectivo color

            % Segmento 1: Parte Vertical (Desde superficie hasta el KOP)
            y=0:10:KOP;
            x=0;
            plot(x,y,'b:.')
            % Segmento 2: Construccion del angulo (desde KOP hasta Final de Curva)
            y2=KOP:0.5:Pvvfc;
            x2=Rc*(1-cosd(asind((y2-KOP)/Rc)));
            plot(x2,y2,'r:.')
            % Segmento 3: Parte Tangente (Línea recta final hacia el objetivo)
            y3=Pvvfc:10:Pvv;
            x3=(y3-Pvvfc)*tand(ang)+DHFC;
            plot(x3,y3,'g:.')
            hold off

            % --- Calculos para determinar los puntos de interes
            if Pvvi<=KOP
                k=Pvvi
                h=0
            elseif KOP<Pvvi && Pvvi<=Pvvfc
                te=asind((Pvvi-KOP)/Rc)
                k=te/RIA+KOP
                h=Rc*(1-cosd(te))
            else
                k=(Pvvi-Pvvfc)/cosd(ang)+PDFC
                h=(Pvvi-Pvvfc)*tand(ang)+DHFC
            end
            if PDdi<=KOP
                r=0
                q=PDdi
            elseif KOP<PDdi && PDdi<=PDFC
                r=Rc*(1-cosd((PDdi-KOP)*RIA))
                q=Rc*sind((PDdi-KOP)*RIA)+KOP
            else
                r=DHFC+(PDdi-PDFC)*sind(ang)
                q=Pvvfc+(PDdi-PDFC)*cosd(ang)
            end

            % --- Muestra los resultados obtenidos en las celdas 
            set(handles.edit14,'String',Lc)
            set(handles.edit6,'String',ang)
            set(handles.edit12,'String',Rc)
            set(handles.edit7,'String',PDFC)
            set(handles.edit15,'String',omega)
            set(handles.edit16,'String',Pvvfc)
            set(handles.edit17,'String',ztan)
            set(handles.edit8,'String',DHFC)
            set(handles.edit9,'String',PDTP)
            set(handles.edit10,'String',k)
            set(handles.edit11,'String',h)
            set(handles.edit20,'String',r)
            set(handles.edit19,'String',q)
        end

        % Button pushed function: pushbutton2
        function pushbutton2_Callback(app, event)
            % --- Executes on button press in pushbutton2.
            
            % Boten de regreso para el menu principal
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
                       
            clc;
            close(gcbf); % Cierra la ventana activa actual (tipo_j) de forma genérica
            run('portada_App.mlapp'); % Abre la portada limpia


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'tipo__j';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Tipo J';
            app.uipanel1.Tag = 'uipanel1';
            app.axes1 = uiaxes(app.uipanel1);
            app.axes1.Tag = 'axes1';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'DH del objetivo [m]:';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Pvv al objetivo [m]:';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'KOP [m]:';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'RIA [°/m]:';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'PD a Pvv de int [m]:';
            app.text7 = uilabel(app.uipanel1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'PDTP [m]:';
            app.text8 = uilabel(app.uipanel1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'PDFC [m]:';
            app.text9 = uilabel(app.uipanel1);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Θ max [°]';
            app.text10 = uilabel(app.uipanel1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'DHFC [m]:';
            app.text11 = uilabel(app.uipanel1);
            app.text11.Tag = 'text11';
            app.text11.Text = 'DH a Pvv de int [m]:';
            app.text12 = uilabel(app.uipanel1);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Pvv de interes [m]:';
            app.pushbutton1 = uibutton(app.uipanel1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'Calcular';
            app.edit1 = uieditfield(app.uipanel1, 'text');
            app.edit1.Tag = 'edit1';
            app.edit2 = uieditfield(app.uipanel1, 'text');
            app.edit2.Tag = 'edit2';
            app.edit3 = uieditfield(app.uipanel1, 'text');
            app.edit3.Tag = 'edit3';
            app.edit4 = uieditfield(app.uipanel1, 'text');
            app.edit4.Tag = 'edit4';
            app.edit5 = uieditfield(app.uipanel1, 'text');
            app.edit5.Tag = 'edit5';
            app.edit6 = uieditfield(app.uipanel1, 'text');
            app.edit6.Tag = 'edit6';
            app.edit7 = uieditfield(app.uipanel1, 'text');
            app.edit7.Tag = 'edit7';
            app.edit8 = uieditfield(app.uipanel1, 'text');
            app.edit8.Tag = 'edit8';
            app.edit9 = uieditfield(app.uipanel1, 'text');
            app.edit9.Tag = 'edit9';
            app.edit10 = uieditfield(app.uipanel1, 'text');
            app.edit10.Tag = 'edit10';
            app.edit11 = uieditfield(app.uipanel1, 'text');
            app.edit11.Tag = 'edit11';
            app.text13 = uilabel(app.uipanel1);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Rc [m]:';
            app.edit12 = uieditfield(app.uipanel1, 'text');
            app.edit12.Tag = 'edit12';
            app.edit13 = uieditfield(app.uipanel1, 'text');
            app.edit13.Tag = 'edit13';
            app.text14 = uilabel(app.uipanel1);
            app.text14.Tag = 'text14';
            app.text14.Text = 'cada';
            app.text15 = uilabel(app.uipanel1);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Lc [m]:';
            app.edit14 = uieditfield(app.uipanel1, 'text');
            app.edit14.Tag = 'edit14';
            app.text16 = uilabel(app.uipanel1);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Ω [°]:';
            app.edit15 = uieditfield(app.uipanel1, 'text');
            app.edit15.Tag = 'edit15';
            app.text17 = uilabel(app.uipanel1);
            app.text17.Tag = 'text17';
            app.text17.Text = 'Pvv a FC [m]:';
            app.edit16 = uieditfield(app.uipanel1, 'text');
            app.edit16.Tag = 'edit16';
            app.text18 = uilabel(app.uipanel1);
            app.text18.Tag = 'text18';
            app.text18.Text = 'L zona Tan [m]:';
            app.edit17 = uieditfield(app.uipanel1, 'text');
            app.edit17.Tag = 'edit17';
            app.text19 = uilabel(app.uipanel1);
            app.text19.Tag = 'text19';
            app.text19.Text = 'PD de interes [m]:';
            app.edit18 = uieditfield(app.uipanel1, 'text');
            app.edit18.Tag = 'edit18';
            app.text20 = uilabel(app.uipanel1);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Pvv a PD de int [m]:';
            app.text21 = uilabel(app.uipanel1);
            app.text21.Tag = 'text21';
            app.text21.Text = 'DH a PD de int [m]:';
            app.edit19 = uieditfield(app.uipanel1, 'text');
            app.edit19.Tag = 'edit19';
            app.edit20 = uieditfield(app.uipanel1, 'text');
            app.edit20.Tag = 'edit20';
            app.pushbutton2 = uibutton(app.uipanel1, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2_Callback, true);
            app.pushbutton2.Tag = 'pushbutton2';
            app.pushbutton2.Text = 'Regresar';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tipo__j_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)tipo__j_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end