classdef tipo_s_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        uipanel1     matlab.ui.container.Panel
        pushbutton2  matlab.ui.control.Button
        edit26       matlab.ui.control.EditField
        text27       matlab.ui.control.Label
        edit25       matlab.ui.control.EditField
        edit24       matlab.ui.control.EditField
        text26       matlab.ui.control.Label
        text25       matlab.ui.control.Label
        edit23       matlab.ui.control.EditField
        edit22       matlab.ui.control.EditField
        text24       matlab.ui.control.Label
        text23       matlab.ui.control.Label
        edit21       matlab.ui.control.EditField
        edit20       matlab.ui.control.EditField
        edit19       matlab.ui.control.EditField
        edit18       matlab.ui.control.EditField
        edit17       matlab.ui.control.EditField
        edit16       matlab.ui.control.EditField
        edit15       matlab.ui.control.EditField
        edit14       matlab.ui.control.EditField
        edit13       matlab.ui.control.EditField
        edit12       matlab.ui.control.EditField
        edit11       matlab.ui.control.EditField
        edit10       matlab.ui.control.EditField
        text22       matlab.ui.control.Label
        text21       matlab.ui.control.Label
        text20       matlab.ui.control.Label
        text19       matlab.ui.control.Label
        text18       matlab.ui.control.Label
        text17       matlab.ui.control.Label
        text16       matlab.ui.control.Label
        text15       matlab.ui.control.Label
        text14       matlab.ui.control.Label
        text13       matlab.ui.control.Label
        text12       matlab.ui.control.Label
        text11       matlab.ui.control.Label
        pushbutton1  matlab.ui.control.Button
        text10       matlab.ui.control.Label
        edit9        matlab.ui.control.EditField
        edit8        matlab.ui.control.EditField
        edit7        matlab.ui.control.EditField
        edit6        matlab.ui.control.EditField
        edit5        matlab.ui.control.EditField
        edit4        matlab.ui.control.EditField
        edit3        matlab.ui.control.EditField
        edit2        matlab.ui.control.EditField
        edit1        matlab.ui.control.EditField
        text9        matlab.ui.control.Label
        text8        matlab.ui.control.Label
        text7        matlab.ui.control.Label
        text6        matlab.ui.control.Label
        text5        matlab.ui.control.Label
        text4        matlab.ui.control.Label
        text3        matlab.ui.control.Label
        text2        matlab.ui.control.Label
        axes1        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function tipo_s_OpeningFcn(app, varargin)
            % Asegura la correcta visualización de la ventana en pantalla
            movegui(app.figure1, 'onscreen');
            
            % Inicialización de variables y punteros de compatibilidad con GUIDE
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            
            handles.output = hObject;
            % Color negro en las celdas
            etiquetas = findobj(hObject, 'Type', 'uilabel');
            set(etiquetas, 'FontColor', [0 0 0]);

            % Color negro en los celdas editables
            cajas_entrada = findobj(hObject, 'Type', 'uieditfield');
            set(cajas_entrada, 'FontColor', [0 0 0]);
            % Actualiza de forma segura la estructura de control de la app
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % Boton de calcular para el tipo S
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            % Resetea el plano gráfico para limpiar simulaciones anteriores
            cla reset

            % Llama los campos editables, que es la informacion que el
            % usuario tiene para calcular el tipo S
            % Desplazamiento Horizontal del objetivo final
            DH=str2double(get(handles.edit1,'String'))
            % Profundidad Vertical Verdadera (PVV) del objetivo
            Pvv=str2double(get(handles.edit2,'String'))
            % Tasa de incremento (Build-up rate) el angulo [°]
            a1=str2double(get(handles.edit3,'String'))
            % Tasa de incremento (Build-up rate) cada que metros [m]
            m1=str2double(get(handles.edit4,'String'))
            % Tasa para tumbar angulo (Drop-off rate) angulo [°]
            a2=str2double(get(handles.edit5,'String'))
            % Tasa para tumbar angulo (Drop-off rate) cada que metros [m]
            m2=str2double(get(handles.edit6,'String'))
            % Profundidad de inicio de construccion del angulo
            KOP=str2double(get(handles.edit7,'String'))

            % Datos si se quiere saber profundidades de interes
            Pvvi=str2double(get(handles.edit8,'String'))
            PDdi=str2double(get(handles.edit9,'String'))

            % Inicia procedimiento para calcular
            % Tasa de incremento (Build-up rate)
            RIA1=a1/m1
            % Tasa para tumbar angulo (Drop-off rate)
            RIA2=a2/m2
            % Radio de Curvatura de la sección de construcción
            Rc1=(180/(pi*RIA1))
            % Radio de Curvatura para tumbar angulo
            Rc2=180/(pi*RIA2)
            % --- DETERMINACIÓN GEOMÉTRICA DE LAS CONSTANTES DE DESPLAZAMIENTO (r, w) ---
            if DH<=(Rc1+Rc2)
                r=Rc1-(DH-Rc2)
                w=Rc1-r
            else
                r=DH-(Rc1+Rc2)
                w=r+Rc1
            end
            % --- RESOLUCIÓN TRIGONOMÉTRICA DE LA INCLINACIÓN MÁXIMA (ama) ---
            aFOE=atand(r/(Pvv-KOP))
            OF=(r^2+(Pvv-KOP)^2)^(0.5)
            aFOG=asind((Rc1+Rc2)/OF)
            ama=aFOG-aFOE
            % --- SECCIÓN DE CONSTRUCCIÓN (BUILD-UP) --
            % Longitud de la primera sección curva
            Lc1=ama/RIA1
            % Profundidad Desarrollada al Final de la Curva
            PDFC=KOP+Lc1
            % Profundidad Vertical Verdadera al Final de la Curva
            PVFC=Rc1*sind(ama)+KOP
            % Desplazamiento Horizontal al Final de la Curva
            DHFC=Rc1*(1-cosd(ama))
            % --- SECCIÓN TANGENTE ---
            % Longitud del segmento recto (zona tangente)
            ltan=(OF^2-(Rc1+Rc2)^2)^(0.5)
            % Profundidad Desarrollada hasta el final de la zona recta
            PDtan=PDFC+ltan
            % Profundidad Vertical hasta el final de la zona recta
            PVtan=PVFC+ltan*cosd(ama)
            % Desplazamiento Horizontal hasta el final de la zona recta
            DHtan=DHFC+ltan*sind(ama)
            % --- SECCIÓN PARA TUMBAR ANGULo
            % Longitud medida del segundo arco de curva
            Lc2=ama/RIA2
            % Profundidad Desarrollada Total del pozo
            PDt=PDtan+Lc2

            % --- Calculos para determinar prof de interes
            if Pvvi<=KOP
                l=0
                m=Pvvi
            else
                if KOP<Pvvi && Pvvi<=PVFC
                    a=asind((Pvvi-KOP)/Rc1)
                    l=Rc1*(1-cosd(a))
                    m=a/RIA1+KOP
                else
                    if PVFC<Pvvi && Pvvi<=PVtan
                        l=(Pvvi-PVFC)*tand(ama)+DHFC
                        m=(Pvvi-PVFC)/cosd(ama)+PDFC
                    else
                        a=asind((Pvv-Pvvi)/Rc2)
                        l=Rc2*(cosd(a))+w
                        as=asind((Pvv-PVtan)/Rc2)
                        m=as/RIA2-a/RIA2+PDtan
                    end
                end
            end
            if PDdi<=KOP
                o=0
                p=PDdi
            else
                if KOP<PDdi && PDdi<=PDFC
                    ap=(Lc1-(PDFC-PDdi))*RIA1
                    o=Rc1*(1-cosd(ap))
                    p=KOP+Rc1*sind(ap)
                else
                    if PDFC<PDdi && PDdi<=PDtan
                        o=sind(ama)*(PDdi-PDFC)+DHFC
                        p=cosd(ama)*(PDdi-PDFC)+PVFC
                    else
                        ap=(Lc2-(PDdi-PDtan))*RIA2
                        o=DH-Rc2*(1-cosd(ap))
                        p=Pvv-Rc2*sind(ap)
                    end
                end
            end

            % --- Codigo para graficar las cuatro secciones, vertical,
            % construccion del angulo, tangente, y para tumbar angulo
            % Invierte el eje Y para reflejar profundidad perforada
            set(gca,'yDir','reverse')
            axis([-20,(DH+50),0,Pvv])
            title('Tipo S')
            xlabel('Desplazamiento Horizontal [m]')
            ylabel('Profundidad [m]')
            hold on
            grid

            % Gráfica Segmento 1: Tramo vertical (Superficie a KOP)
            y=0:10:KOP;
            x=0;
            plot(x,y,'b:.')
            % Gráfica Segmento 2: Curva de Construcción (KOP a Final de Curva)
            y2=KOP:0.5:PVFC;
            x2=Rc1*(1-cosd(asind((y2-KOP)/Rc1)));
            plot(x2,y2,'r:.')
            % Gráfica Segmento 3: Tramo Recto Tangencial
            y3=PVFC:5:PVtan;
            x3=(y3-PVFC)*tand(ama)+DHFC;
            plot(x3,y3,'g:.')
            % Gráfica Segmento 4: Curva para tumbar angulo (Final de Recta a Objetivo)
            y4=PVtan:0.5:Pvv;
            x4=w+Rc2*cosd(asind((Pvv-y4)/Rc2));
            plot(x4,y4,'k:.')
            hold off
            
            % --- Mostrar los resultados obtuvidos 
            set(handles.edit10,'String',Rc1)
            set(handles.edit11,'String',Rc2)
            set(handles.edit12,'String',ama)
            set(handles.edit13,'String',Lc1)
            set(handles.edit14,'String',PDFC)
            set(handles.edit15,'String',PVFC)
            set(handles.edit16,'String',DHFC)
            set(handles.edit17,'String',ltan)
            set(handles.edit18,'String',PDtan)
            set(handles.edit19,'String',PVtan)
            set(handles.edit20,'String',DHtan)
            set(handles.edit21,'String',PDt)
            set(handles.edit22,'String',m)
            set(handles.edit23,'String',l)
            set(handles.edit24,'String',p)
            set(handles.edit25,'String',o)
            set(handles.edit26,'String',Lc2)
        end

        % Button pushed function: pushbutton2
        function pushbutton2_Callback(app, event)
            % --- Executes on button press in pushbutton2.
            
            % Boton para regresar al menu
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Limpia la consola de MATLAB
            clc;
            close(gcbf); % Cierra la ventana activa actual (tipo_j) de forma genérica
            run('portada_App.mlapp'); % Abre la portada limpia

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'tipo_s';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Tipo S';
            app.uipanel1.Tag = 'uipanel1';
            app.axes1 = uiaxes(app.uipanel1);
            app.axes1.Tag = 'axes1';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'DH al obj [m]:';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Pvv al obj [m]:';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'RIA (aumento) [°/m]:';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'RIA (disminución) [°/m]:';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'KOP [m]:';
            app.text7 = uilabel(app.uipanel1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'PV de int. [m]:';
            app.text8 = uilabel(app.uipanel1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'PD de int. [m]:';
            app.text9 = uilabel(app.uipanel1);
            app.text9.Tag = 'text9';
            app.text9.Text = 'cada';
            app.edit1 = uieditfield(app.uipanel1, 'text');
            app.edit1.Tag = 'edit1';
            app.edit2 = uieditfield(app.uipanel1, 'text');
            app.edit2.Tag = 'edit2';
            app.edit3 = uieditfield(app.uipanel1, 'text');
            app.edit3.Tag = 'edit3';
            app.edit4 = uieditfield(app.uipanel1, 'text');
            app.edit4.Tag = 'edit4';
            app.edit5 = uieditfield(app.uipanel1, 'text');
            app.edit5.Tag = 'edit5';
            app.edit6 = uieditfield(app.uipanel1, 'text');
            app.edit6.Tag = 'edit6';
            app.edit7 = uieditfield(app.uipanel1, 'text');
            app.edit7.Tag = 'edit7';
            app.edit8 = uieditfield(app.uipanel1, 'text');
            app.edit8.Tag = 'edit8';
            app.edit9 = uieditfield(app.uipanel1, 'text');
            app.edit9.Tag = 'edit9';
            app.text10 = uilabel(app.uipanel1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'cada';
            app.pushbutton1 = uibutton(app.uipanel1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'Calcular';
            app.text11 = uilabel(app.uipanel1);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Rc1 [m]:';
            app.text12 = uilabel(app.uipanel1);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Rc2 [m]:';
            app.text13 = uilabel(app.uipanel1);
            app.text13.Tag = 'text13';
            app.text13.Text = 'θ max [°]:';
            app.text14 = uilabel(app.uipanel1);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Lc1 [m]:';
            app.text15 = uilabel(app.uipanel1);
            app.text15.Tag = 'text15';
            app.text15.Text = 'PDFC [m]:';
            app.text16 = uilabel(app.uipanel1);
            app.text16.Tag = 'text16';
            app.text16.Text = 'PVFC [m]:';
            app.text17 = uilabel(app.uipanel1);
            app.text17.Tag = 'text17';
            app.text17.Text = 'DHFC [m]:';
            app.text18 = uilabel(app.uipanel1);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Lon. zona tang. [m]:';
            app.text19 = uilabel(app.uipanel1);
            app.text19.Tag = 'text19';
            app.text19.Text = 'PD hasta zona tang. [m]:';
            app.text20 = uilabel(app.uipanel1);
            app.text20.Tag = 'text20';
            app.text20.Text = 'PV hasta zona tang. [m]:';
            app.text21 = uilabel(app.uipanel1);
            app.text21.Tag = 'text21';
            app.text21.Text = 'DH hasta zona tang. [m]:';
            app.text22 = uilabel(app.uipanel1);
            app.text22.Tag = 'text22';
            app.text22.Text = 'PD al obj. [m]:';
            app.edit10 = uieditfield(app.uipanel1, 'text');
            app.edit10.Tag = 'edit10';
            app.edit11 = uieditfield(app.uipanel1, 'text');
            app.edit11.Tag = 'edit11';
            app.edit12 = uieditfield(app.uipanel1, 'text');
            app.edit12.Tag = 'edit12';
            app.edit13 = uieditfield(app.uipanel1, 'text');
            app.edit13.Tag = 'edit13';
            app.edit14 = uieditfield(app.uipanel1, 'text');
            app.edit14.Tag = 'edit14';
            app.edit15 = uieditfield(app.uipanel1, 'text');
            app.edit15.Tag = 'edit15';
            app.edit16 = uieditfield(app.uipanel1, 'text');
            app.edit16.Tag = 'edit16';
            app.edit17 = uieditfield(app.uipanel1, 'text');
            app.edit17.Tag = 'edit17';
            app.edit18 = uieditfield(app.uipanel1, 'text');
            app.edit18.Tag = 'edit18';
            app.edit19 = uieditfield(app.uipanel1, 'text');
            app.edit19.Tag = 'edit19';
            app.edit20 = uieditfield(app.uipanel1, 'text');
            app.edit20.Tag = 'edit20';
            app.edit21 = uieditfield(app.uipanel1, 'text');
            app.edit21.Tag = 'edit21';
            app.text23 = uilabel(app.uipanel1);
            app.text23.Tag = 'text23';
            app.text23.Text = 'PD a PV de int. [m]:';
            app.text24 = uilabel(app.uipanel1);
            app.text24.Tag = 'text24';
            app.text24.Text = 'DH a PV de int. [m]:';
            app.edit22 = uieditfield(app.uipanel1, 'text');
            app.edit22.Tag = 'edit22';
            app.edit23 = uieditfield(app.uipanel1, 'text');
            app.edit23.Tag = 'edit23';
            app.text25 = uilabel(app.uipanel1);
            app.text25.Tag = 'text25';
            app.text25.Text = 'PV a PD de int. [m]:';
            app.text26 = uilabel(app.uipanel1);
            app.text26.Tag = 'text26';
            app.text26.Text = 'DH a PD de int.[m]:';
            app.edit24 = uieditfield(app.uipanel1, 'text');
            app.edit24.Tag = 'edit24';
            app.edit25 = uieditfield(app.uipanel1, 'text');
            app.edit25.Tag = 'edit25';
            app.text27 = uilabel(app.uipanel1);
            app.text27.Tag = 'text27';
            app.text27.Text = 'Lc2 [m]:';
            app.edit26 = uieditfield(app.uipanel1, 'text');
            app.edit26.Tag = 'edit26';
            app.pushbutton2 = uibutton(app.uipanel1, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2_Callback, true);
            app.pushbutton2.Tag = 'pushbutton2';
            app.pushbutton2.Text = 'Regresar';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tipo_s_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)tipo_s_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end