classdef tipo_smoddd_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        uipanel1     matlab.ui.container.Panel
        pushbutton2  matlab.ui.control.Button
        edit38       matlab.ui.control.EditField
        edit37       matlab.ui.control.EditField
        edit36       matlab.ui.control.EditField
        text39       matlab.ui.control.Label
        text38       matlab.ui.control.Label
        text37       matlab.ui.control.Label
        edit35       matlab.ui.control.EditField
        text36       matlab.ui.control.Label
        edit34       matlab.ui.control.EditField
        text35       matlab.ui.control.Label
        edit30       matlab.ui.control.EditField
        text31       matlab.ui.control.Label
        edit29       matlab.ui.control.EditField
        edit28       matlab.ui.control.EditField
        edit27       matlab.ui.control.EditField
        text30       matlab.ui.control.Label
        text29       matlab.ui.control.Label
        text28       matlab.ui.control.Label
        edit26       matlab.ui.control.EditField
        text27       matlab.ui.control.Label
        edit25       matlab.ui.control.EditField
        text26       matlab.ui.control.Label
        edit24       matlab.ui.control.EditField
        text25       matlab.ui.control.Label
        edit23       matlab.ui.control.EditField
        edit22       matlab.ui.control.EditField
        edit21       matlab.ui.control.EditField
        text24       matlab.ui.control.Label
        text23       matlab.ui.control.Label
        text22       matlab.ui.control.Label
        edit20       matlab.ui.control.EditField
        text21       matlab.ui.control.Label
        edit19       matlab.ui.control.EditField
        text20       matlab.ui.control.Label
        edit18       matlab.ui.control.EditField
        text19       matlab.ui.control.Label
        edit17       matlab.ui.control.EditField
        edit16       matlab.ui.control.EditField
        edit15       matlab.ui.control.EditField
        text18       matlab.ui.control.Label
        text17       matlab.ui.control.Label
        text16       matlab.ui.control.Label
        pushbutton1  matlab.ui.control.Button
        edit12       matlab.ui.control.EditField
        text13       matlab.ui.control.Label
        edit11       matlab.ui.control.EditField
        text12       matlab.ui.control.Label
        edit10       matlab.ui.control.EditField
        text11       matlab.ui.control.Label
        text10       matlab.ui.control.Label
        edit9        matlab.ui.control.EditField
        edit8        matlab.ui.control.EditField
        edit7        matlab.ui.control.EditField
        edit6        matlab.ui.control.EditField
        edit5        matlab.ui.control.EditField
        edit4        matlab.ui.control.EditField
        edit3        matlab.ui.control.EditField
        edit2        matlab.ui.control.EditField
        edit1        matlab.ui.control.EditField
        text9        matlab.ui.control.Label
        text8        matlab.ui.control.Label
        text7        matlab.ui.control.Label
        text6        matlab.ui.control.Label
        text5        matlab.ui.control.Label
        text4        matlab.ui.control.Label
        text3        matlab.ui.control.Label
        text2        matlab.ui.control.Label
        axes1        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function tipo_smoddd_OpeningFcn(app, varargin)
            % --- Executes just before tipo_smoddd is made visible.
            
            % Garantiza la correcta visualización de la ventana de la app en la pantalla
            movegui(app.figure1, 'onscreen');
            
            % Inicialización de variables de compatibilidad para el entorno GUIDE portado
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
                        
            % Choose default command line output for tipo_smoddd
            handles.output = hObject;
            
            % 1. Color negro al texto
            etiquetas = findobj(hObject, 'Type', 'uilabel');
            set(etiquetas, 'FontColor', [0 0 0]);

            % 2. Color oscuro en las celdas modificables
            cajas_entrada = findobj(hObject, 'Type', 'uieditfield');
            set(cajas_entrada, 'FontColor', [0 0 0]);
            % Actualiza de forma segura la estructura de control de la interfaz
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % --- Executes on button press in pushbutton1.
            
            % Boton para calcular
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Limpia el plano de coordenadas para evitar la superposición de gráficas previas
            cla reset
            % llama las celdas donde el usuario ingresa los datos
            % Desplazamiento Horizontal al objetivo final
            DH=str2double(get(handles.edit1,'String'))
            % Profundidad Vertical Verdadera (PVV) al objetivo final
            Pvv=str2double(get(handles.edit2,'String'))
            % Tasa de incremento angular (Build-up rate) angulo [°]
            a1=str2double(get(handles.edit3,'String'))
            % Tasa de incremento angular (Build-up rate) cada que metros
            % [m]
            m1=str2double(get(handles.edit4,'String'))
            % Tasa para tumbar angulo (Drop-off rate) angulo [°]
            a2=str2double(get(handles.edit5,'String'))
            % Tasa para tumbar angulo (Drop-off rate) cada metros [m]
            m2=str2double(get(handles.edit6,'String'))
            % Profundidad del punto de inicio de construccion de angulo
            KOP=str2double(get(handles.edit7,'String'))
            % Nota: durante la resolucion para determinar la trayectoria
            % direccional para el tipo S Modificado, se necesitan variables
            % adicionales, como:
            % Desplazamiento Horizontal a inicio para tumbar angulo
            DHt=str2double(get(handles.edit8,'String'))
            % Desplazamiento Horizontal despues de tumbar angulo
            DHp=str2double(get(handles.edit9,'String'))
            % Profundidad Vertical despues de tumbar angulo
            Pvvt=str2double(get(handles.edit10,'String'))
            % Datos para profundidad de interes del usuario
            Pvvi=str2double(get(handles.edit11,'String'))
            PDdi=str2double(get(handles.edit12,'String'))

            % Inicia calculos para el programa
            % Ritmo de incremento del angulo
            RIA1=a1/m1
            % Ritmo para tumbar angulo
            RIA2=a2/m2
            % Radios de curvatura de cada una
            Rc1=(180/(pi*RIA1))
            Rc2=180/(pi*RIA2)
            % --- RESOLUCIÓN DE CONSTANTES GEOMÉTRICAS DE DESVIACIÓN (r, w)
            if DHt<=(Rc1+Rc2)
                r=Rc1-(DHt-Rc2)
                w=Rc1-r
            else
                r=DHt-(Rc1+Rc2)
                w=r+Rc1
            end
            % --- TRIGONOMETRÍA DEL ÁNGULO MÁXIMO DE INCLINACIÓN (ama)
            aFOE=atand(r/(Pvvt-KOP))
            OF=(r^2+(Pvvt-KOP)^2)^(0.5)
            aFOG=asind((Rc1+Rc2)/OF)
            ama=aFOG-aFOE
            % --- SECCIÓN DE CONSTRUCCIÓN DEL ANGULO
            % Longitud de la primera curva
            Lc1=ama/RIA1
            % Profundidad Desarrollada al Final de la Primera Curva
            PDFC=KOP+Lc1
            % Profundidad Vertical Verdadera al Final de la Primera Curva
            PVFC=Rc1*sind(ama)+KOP
            % Desplazamiento Horizontal al Final de la Primera Curva
            DHFC=Rc1*(1-cosd(ama))
            % --- PRIMERA SECCIÓN TANGENCIAL (TRAMO RECTO INTERMEDIO)
            % Longitud espacial del segmento recto
            ltan=(OF^2-(Rc1+Rc2)^2)^(0.5)
            % Profundidad Desarrollada acumulada al final de la recta
            PDtan=PDFC+ltan
            % Profundidad Vertical acumulada al final de la recta
            PVtan=PVFC+ltan*cosd(ama)
            % Desplazamiento Horizontal acumulado al final de la recta
            DHtan=DHFC+ltan*sind(ama)
            % --- SECCIÓN DE ABATIMIENTO DEL ANGULO (DROP-OFF MODIFICADO)
            pend=-(-(DHt-Rc2)+DHp)/((Rc2^2-((DHt-Rc2)-DHp)^2)^(1/2))
            % Ángulo residual final del pozo
            ag=-1*atand((-10)/(pend*-10))
            % Ángulo de caída real de la sección curva
            ama2=ama-ag
            % Longitud medida del segundo tramo curvo
            Lc2=ama2/RIA2
            % Profundidad Vertical al finalizar la curva de caída (FC2)
            Pvvp=Pvvt-Rc2*sind(ag)
            % Profundidad Desarrollada al Final de la Segunda Curva
            PDfc2=PDtan+Lc2
             % --- SEGUNDA SECCIÓN TANGENCIAL (TRAMO FINAL EN DIAGONAL) ---
            % Longitud del tramo recto final hacia el objetivo
            ltan2=((Pvv-Pvvp)^2+(DH-DHp)^2)^(1/2)
            % Profundidad Desarrollada Total calculada del pozo
            PDt=ltan2+PDfc2
            
            % --- INTERPOLACIÓN MATEMÁTICA POR PROFUNDIDAD VERTICAL (Pvvi) 
            if Pvvi<=KOP
                l=0
                m=Pvvi
            else
                if KOP<Pvvi && Pvvi<=PVFC
                    a=asind((Pvvi-KOP)/Rc1)
                    l=Rc1*(1-cosd(a))
                    m=a/RIA1+KOP
                else
                    if PVFC<Pvvi && Pvvi<=PVtan
                        l=(Pvvi-PVFC)*tand(ama)+DHFC
                        m=(Pvvi-PVFC)/cosd(ama)+PDFC
                    else
                        if PVtan<Pvvi && Pvvi<=Pvvp
                            a=asind((Pvvt-Pvvi)/Rc2)
                            l=Rc2*(cosd(a))+w
                            m=(ama-a)/RIA2+PDtan
                        else
                            l=(DH-DHp)*(Pvvi-Pvvp)/(Pvv-Pvvp)+DHp
                            m=((Pvvi-Pvvp)^2+(l-DHp)^2)^(1/2)+PDfc2
                        end
                    end
                end
            end
            if PDdi<=KOP
                o=0
                p=PDdi
            else
                if KOP<PDdi && PDdi<=PDFC
                    ap=(Lc1-(PDFC-PDdi))*RIA1
                    o=Rc1*(1-cosd(ap))
                    p=KOP+Rc1*sind(ap)
                else
                    if PDFC<PDdi && PDdi<=PDtan
                        o=sind(ama)*(PDdi-PDFC)+DHFC
                        p=cosd(ama)*(PDdi-PDFC)+PVFC
                    else
                        if PDtan<PDdi && PDdi<=PDfc2
                            ap=ama-((PDdi-PDtan))*RIA2
                            o=Rc2*(cosd(ap))+w
                            p=Pvvt-Rc2*sind(ap)
                        else
                            o=(DH-DHp)*(PDdi-PDfc2)/ltan2+DHp
                            p=(Pvv-Pvvp)*(PDdi-PDfc2)/ltan2+Pvvp
                        end
                    end
                end
            end

            % --- Para graficar los 5 segmentos, la parte vertical, la
            % contruccion del angulo, la tangente, la parte para tumbar
            % angulo, y la ultima tangente hacia el objetivo
            % Invierte el eje Y para proyectar hacia el fondo a perforar
            set(gca,'yDir','reverse')
            axis([-50,(DH+100),0,Pvv])
            title('Tipo S Modificado')
            xlabel('Desplazamiento Horizontal [m]')
            ylabel('Profundidad [m]')
            hold on
            grid
            % Segmento 1: Línea recta vertical (Superficie a KOP)
            y=0:10:KOP;
            x=0;
            plot(x,y,'b:.')
            % Segmento 2: Curva de incremento angular (Build-up)
            y2=KOP:0.5:PVFC;
            x2=Rc1*(1-cosd(asind((y2-KOP)/Rc1)));
            plot(x2,y2,'r:.')
            % Segmento 3: Sección Tangente
            y3=PVFC:5:PVtan;
            x3=(y3-PVFC)*tand(ama)+DHFC;
            plot(x3,y3,'g:.')
            % Segmento 4: Para tumbar angulo
            y4=PVtan:0.5:Pvvp;
            x4=w+Rc2*cosd(asind((Pvvt-y4)/Rc2));
            plot(x4,y4,'k:.')
            % Segmento 5: Ultima Sección Tangente
            y5=Pvvp:10:Pvv
            x5=(DH-DHp)*(y5-Pvvp)/(Pvv-Pvvp)+DHp
            plot(x5,y5,'m:.')
            hold off
            
            % --- Imprime o manda los resultados a las celdas
            set(handles.edit15,'String',Rc1)
            set(handles.edit25,'String',Rc2)
            set(handles.edit16,'String',ama)
            set(handles.edit17,'String',Lc1)
            set(handles.edit18,'String',PDFC)
            set(handles.edit19,'String',PVFC)
            set(handles.edit20,'String',DHFC)
            set(handles.edit21,'String',ltan)
            set(handles.edit22,'String',PDtan)
            set(handles.edit23,'String',PVtan)
            set(handles.edit24,'String',DHtan)
            set(handles.edit26,'String',ama2)
            set(handles.edit27,'String',Lc2)
            set(handles.edit28,'String',Pvvp)
            set(handles.edit29,'String',PDfc2)
            set(handles.edit30,'String',ltan2)
            set(handles.edit34,'String',PDt)
            set(handles.edit35,'String',m)
            set(handles.edit36,'String',l)
            set(handles.edit37,'String',p)
            set(handles.edit38,'String',o)
        end

        % Button pushed function: pushbutton2
        function pushbutton2_Callback(app, event)
            % --- Executes on button press in pushbutton2.
            
            % Boton para regresar al menu principal
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Limpia el espacio de la consola de MATLAB
            clc;
            close(gcbf); % Cierra la ventana activa actual (tipo_j) de forma genérica
            run('portada_App.mlapp'); % Abre la portada limpia

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'tipo_smoddd';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'S Modificado';
            app.uipanel1.Tag = 'uipanel1';
            app.axes1 = uiaxes(app.uipanel1);
            app.axes1.Tag = 'axes1';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'DH al obj [m]:';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Pvv al obj [m]:';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'RIA (aumento) [°/m]:';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'RIA (disminución) [°/m]:';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'KOP [m]:';
            app.text7 = uilabel(app.uipanel1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'DH Total a FC2 [m]:';
            app.text8 = uilabel(app.uipanel1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'DH Parcial a FC2 [m]:';
            app.text9 = uilabel(app.uipanel1);
            app.text9.Tag = 'text9';
            app.text9.Text = 'cada';
            app.edit1 = uieditfield(app.uipanel1, 'text');
            app.edit1.Tag = 'edit1';
            app.edit2 = uieditfield(app.uipanel1, 'text');
            app.edit2.Tag = 'edit2';
            app.edit3 = uieditfield(app.uipanel1, 'text');
            app.edit3.Tag = 'edit3';
            app.edit4 = uieditfield(app.uipanel1, 'text');
            app.edit4.Tag = 'edit4';
            app.edit5 = uieditfield(app.uipanel1, 'text');
            app.edit5.Tag = 'edit5';
            app.edit6 = uieditfield(app.uipanel1, 'text');
            app.edit6.Tag = 'edit6';
            app.edit7 = uieditfield(app.uipanel1, 'text');
            app.edit7.Tag = 'edit7';
            app.edit8 = uieditfield(app.uipanel1, 'text');
            app.edit8.Tag = 'edit8';
            app.edit9 = uieditfield(app.uipanel1, 'text');
            app.edit9.Tag = 'edit9';
            app.text10 = uilabel(app.uipanel1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'cada';
            app.text11 = uilabel(app.uipanel1);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Pvv Total a FC2 [m]:';
            app.edit10 = uieditfield(app.uipanel1, 'text');
            app.edit10.Tag = 'edit10';
            app.text12 = uilabel(app.uipanel1);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Pvv de interes [m]:';
            app.edit11 = uieditfield(app.uipanel1, 'text');
            app.edit11.Tag = 'edit11';
            app.text13 = uilabel(app.uipanel1);
            app.text13.Tag = 'text13';
            app.text13.Text = 'PD de interes [m]:';
            app.edit12 = uieditfield(app.uipanel1, 'text');
            app.edit12.Tag = 'edit12';
            app.pushbutton1 = uibutton(app.uipanel1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'Calcular';
            app.text16 = uilabel(app.uipanel1);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Rc1 [m]:';
            app.text17 = uilabel(app.uipanel1);
            app.text17.Tag = 'text17';
            app.text17.Text = 'θ1 max [°]:';
            app.text18 = uilabel(app.uipanel1);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Lc2 [m]:';
            app.edit15 = uieditfield(app.uipanel1, 'text');
            app.edit15.Tag = 'edit15';
            app.edit16 = uieditfield(app.uipanel1, 'text');
            app.edit16.Tag = 'edit16';
            app.edit17 = uieditfield(app.uipanel1, 'text');
            app.edit17.Tag = 'edit17';
            app.text19 = uilabel(app.uipanel1);
            app.text19.Tag = 'text19';
            app.text19.Text = 'PDFC 1 [m]:';
            app.edit18 = uieditfield(app.uipanel1, 'text');
            app.edit18.Tag = 'edit18';
            app.text20 = uilabel(app.uipanel1);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Pvv FC 1 [m]:';
            app.edit19 = uieditfield(app.uipanel1, 'text');
            app.edit19.Tag = 'edit19';
            app.text21 = uilabel(app.uipanel1);
            app.text21.Tag = 'text21';
            app.text21.Text = 'DHFC 1 [m]:';
            app.edit20 = uieditfield(app.uipanel1, 'text');
            app.edit20.Tag = 'edit20';
            app.text22 = uilabel(app.uipanel1);
            app.text22.Tag = 'text22';
            app.text22.Text = 'Long. 1 zona tang. [m]:';
            app.text23 = uilabel(app.uipanel1);
            app.text23.Tag = 'text23';
            app.text23.Text = 'PD final zona tang. 1 [m]:';
            app.text24 = uilabel(app.uipanel1);
            app.text24.Tag = 'text24';
            app.text24.Text = 'Pvv final zona tang. 1 [m]:';
            app.edit21 = uieditfield(app.uipanel1, 'text');
            app.edit21.Tag = 'edit21';
            app.edit22 = uieditfield(app.uipanel1, 'text');
            app.edit22.Tag = 'edit22';
            app.edit23 = uieditfield(app.uipanel1, 'text');
            app.edit23.Tag = 'edit23';
            app.text25 = uilabel(app.uipanel1);
            app.text25.Tag = 'text25';
            app.text25.Text = 'DH final zona tang. 2 [m]:';
            app.edit24 = uieditfield(app.uipanel1, 'text');
            app.edit24.Tag = 'edit24';
            app.text26 = uilabel(app.uipanel1);
            app.text26.Tag = 'text26';
            app.text26.Text = 'Rc2 [m]:';
            app.edit25 = uieditfield(app.uipanel1, 'text');
            app.edit25.Tag = 'edit25';
            app.text27 = uilabel(app.uipanel1);
            app.text27.Tag = 'text27';
            app.text27.Text = 'θ2 [°]:';
            app.edit26 = uieditfield(app.uipanel1, 'text');
            app.edit26.Tag = 'edit26';
            app.text28 = uilabel(app.uipanel1);
            app.text28.Tag = 'text28';
            app.text28.Text = 'Lc2 [m]:';
            app.text29 = uilabel(app.uipanel1);
            app.text29.Tag = 'text29';
            app.text29.Text = 'Pvv Parcial a FC2 [m]:';
            app.text30 = uilabel(app.uipanel1);
            app.text30.Tag = 'text30';
            app.text30.Text = 'PD Parcial a FC2 [m]:';
            app.edit27 = uieditfield(app.uipanel1, 'text');
            app.edit27.Tag = 'edit27';
            app.edit28 = uieditfield(app.uipanel1, 'text');
            app.edit28.Tag = 'edit28';
            app.edit29 = uieditfield(app.uipanel1, 'text');
            app.edit29.Tag = 'edit29';
            app.text31 = uilabel(app.uipanel1);
            app.text31.Tag = 'text31';
            app.text31.Text = 'Long. 2 zona tang. [m]:';
            app.edit30 = uieditfield(app.uipanel1, 'text');
            app.edit30.Tag = 'edit30';
            app.text35 = uilabel(app.uipanel1);
            app.text35.Tag = 'text35';
            app.text35.Text = 'PD al obj [m]:';
            app.edit34 = uieditfield(app.uipanel1, 'text');
            app.edit34.Tag = 'edit34';
            app.text36 = uilabel(app.uipanel1);
            app.text36.Tag = 'text36';
            app.text36.Text = 'PD a Pvv int. [m]:';
            app.edit35 = uieditfield(app.uipanel1, 'text');
            app.edit35.Tag = 'edit35';
            app.text37 = uilabel(app.uipanel1);
            app.text37.Tag = 'text37';
            app.text37.Text = 'DH a Pvv int. [m]:';
            app.text38 = uilabel(app.uipanel1);
            app.text38.Tag = 'text38';
            app.text38.Text = 'Pvv a PD int. [m]:';
            app.text39 = uilabel(app.uipanel1);
            app.text39.Tag = 'text39';
            app.text39.Text = 'DH a PD int. [m]:';
            app.edit36 = uieditfield(app.uipanel1, 'text');
            app.edit36.Tag = 'edit36';
            app.edit37 = uieditfield(app.uipanel1, 'text');
            app.edit37.Tag = 'edit37';
            app.edit38 = uieditfield(app.uipanel1, 'text');
            app.edit38.Tag = 'edit38';
            app.pushbutton2 = uibutton(app.uipanel1, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2_Callback, true);
            app.pushbutton2.Tag = 'pushbutton2';
            app.pushbutton2.Text = 'Regresar';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tipo_smoddd_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)tipo_smoddd_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end